classdef app_under_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        DutyCycleEditField       matlab.ui.control.NumericEditField
        DutyCycleEditFieldLabel  matlab.ui.control.Label
        PlaySoundSwitch          matlab.ui.control.Switch
        PlaySoundSwitchLabel     matlab.ui.control.Label
        FrequencyEditField       matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel  matlab.ui.control.Label
        PlotButton               matlab.ui.control.Button
        AmplitudeSlider          matlab.ui.control.Slider
        AmplitudeSliderLabel     matlab.ui.control.Label
        TimeSlider               matlab.ui.control.Slider
        TimeSliderLabel          matlab.ui.control.Label
        PlotButtonGroup          matlab.ui.container.ButtonGroup
        SpectogramButton         matlab.ui.control.RadioButton
        FrequencyButton          matlab.ui.control.RadioButton
        TimeButton               matlab.ui.control.RadioButton
        GenerateButtonGroup      matlab.ui.container.ButtonGroup
        SincButton               matlab.ui.control.RadioButton
        ChirpButton              matlab.ui.control.RadioButton
        TriangleButton           matlab.ui.control.RadioButton
        SquareButton             matlab.ui.control.RadioButton
        SineButton               matlab.ui.control.RadioButton
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        x % signal
        t % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Callback function
        function FrequencySliderValueChanged(app, event)
            % value = app.FrequencySlider.Value;
            % title(app.UIAxes, num2str(app.FrequencySlider.Value));
        end

        % Callback function
        function GenerateButtonGroupSelectionChanged(app, event)
            % selectedButton = app.GenerateButtonGroup.SelectedObject.Text;
            % %title(app.UIAxes, selectedButton);
            % A = app.AmplitudeSlider.Value;
            % T = app.TimeSlider.Value;
            % % f = app.FrequencySlider.Value;
            % f = app.FrequencyEditField.Value;
            % fs = 8000;
            % app.t = 0:1/fs:T-1/fs;
            % switch selectedButton
            %     case 'Sine'
            %         app.x = A*sin(2*pi*f*app.t);
            % 
            %     case 'Square'
            %         app.x = A*square(2*pi*f*app.t);
            % 
            %     case 'Triangle'
            %         app.x = A*sawtooth(2*pi*f*app.t, 0.5);
            % 
            %     case 'Chirp'
            %         app.x = A*chirp(app.t, f, app.t(end), 2*f);
            % 
            %     case 'Sinc'
            %         app.x = A*sinc(2*f*(app.t - app.t(end)/2));
            % 
            %             end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)

        selectedPlot = app.PlotButtonGroup.SelectedObject.Text;

        fs = 8000;
        A = app.AmplitudeSlider.Value;

        T = app.TimeSlider.Value;

        f = app.FrequencyEditField.Value;

    app.t = 0:1/fs:T-1/fs;
selectedButton = app.GenerateButtonGroup.SelectedObject.Text;

            % Generate signal
            switch selectedButton

                case 'Sine'

                    app.x = A*sin(2*pi*f*app.t);

                case 'Square'
                    D = app.DutyCycleEditField.Value;
                    app.x = A*square(2*pi*f*app.t);

                case 'Triangle'
                    D = app.DutyCycleEditField.Value;
                    app.x = A*sawtooth(2*pi*f*app.t, 0.5);

                case 'Chirp'

                    app.x = A*chirp(app.t, f, app.t(end), 2*f);

                case 'Sinc'

                    app.x = A*sinc(2*f*(app.t - app.t(end)/2));

            end 
    switch selectedPlot

        case 'Time'

            plot(app.UIAxes, app.t, app.x);

            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Amplitude');
            title(app.UIAxes, 'Time Domain');

        case 'Frequency'

            N = length(app.x);

            X = abs(fft(app.x));

            freq = (0:N-1)*(fs/N);

            plot(app.UIAxes, freq, X);

            xlim(app.UIAxes, [0 500]);

            xlabel(app.UIAxes, 'Frequency');
            ylabel(app.UIAxes, 'Magnitude');
            title(app.UIAxes, 'Frequency Spectrum');

        case 'Spectogram'

            figure;

            spectrogram(app.x, 128, 120, 128, fs, 'yaxis');

            title('Spectrogram');
    end
    zoom(app.UIFigure, 'on');
    if strcmp(app.PlaySoundSwitch.Value, "On")

                clear sound

sound(app.x, fs);
% app.UIFigure.CloseRequestFcn = @(src,event) clear sound;

            end

        end

        % Callback function
        function GenerateButtonGroupButtonDown(app, event)
    %         selectedButton = app.GenerateButtonGroup.SelectedObject.Text;
    %         %title(app.UIAxes, selectedButton);
    %         A = app.AmplitudeSlider.Value;
    %         T = app.TimeSlider.Value;
    %         f = app.FrequencySlider.Value;
    %         fs = 8000;
    %         app.t = 0:1/fs:T-1/fs;
    %         switch selectedButton
    %             case 'Sine'
    %     app.x = A*sin(2*pi*f*app.t);
    % 
    % case 'Square'
    %     app.x = A*square(2*pi*f*app.t);
    % 
    % case 'Triangle'
    %     app.x = A*sawtooth(2*pi*f*app.t, 0.5);
    % 
    % case 'Chirp'
    %     app.x = A*chirp(app.t, f, app.t(end), 2*f);
    % 
    % case 'Sinc'
    %     app.x = A*sinc(2*f*(app.t - app.t(end)/2));
    % 
    % 
    %         end
        end

        % Selection changed function: GenerateButtonGroup
        function GenerateButtonGroupSelectionChanged2(app, event)
         
            selectedButton = app.GenerateButtonGroup.SelectedObject.Text;
            %title(app.UIAxes, selectedButton);
            A = app.AmplitudeSlider.Value;
            T = app.TimeSlider.Value;
            f = app.FrequencyEditField.Value;
            % f = app.FrequencySlider.Value;
            fs = 8000;
            app.t = 0:1/fs:T-1/fs;
            switch selectedButton

                case 'Sine'
                    app.x = A*sin(2*pi*f*app.t);
            
                case 'Square'
                    app.x = A*square(2*pi*f*app.t);
            
                case 'Triangle'
                    app.x = A*sawtooth(2*pi*f*app.t, 0.5);
            
                case 'Chirp'
                    app.x = A*chirp(app.t, f, app.t(end), 2*f);
            
                case 'Sinc'
                    app.x = A*sinc(2*f*(app.t - app.t(end)/2));

end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.GenerateButtonGroup = uibuttongroup(app.UIFigure);
            app.GenerateButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GenerateButtonGroupSelectionChanged2, true);
            app.GenerateButtonGroup.Title = 'Generate';
            app.SineButton = uiradiobutton(app.GenerateButtonGroup);
            app.SineButton.Text = 'Sine';
            app.SineButton.Interpreter = 'latex';
            app.SquareButton = uiradiobutton(app.GenerateButtonGroup);
            app.SquareButton.Text = 'Square';
            app.SquareButton.Interpreter = 'latex';
            app.TriangleButton = uiradiobutton(app.GenerateButtonGroup);
            app.TriangleButton.Text = 'Triangle';
            app.TriangleButton.Interpreter = 'latex';
            app.TriangleButton.Value = true;
            app.ChirpButton = uiradiobutton(app.GenerateButtonGroup);
            app.ChirpButton.Text = 'Chirp';
            app.ChirpButton.Interpreter = 'latex';
            app.SincButton = uiradiobutton(app.GenerateButtonGroup);
            app.SincButton.Text = 'Sinc';
            app.SincButton.Interpreter = 'latex';
            app.PlotButtonGroup = uibuttongroup(app.UIFigure);
            app.PlotButtonGroup.Title = 'Plot';
            app.TimeButton = uiradiobutton(app.PlotButtonGroup);
            app.TimeButton.Text = 'Time';
            app.TimeButton.Interpreter = 'latex';
            app.TimeButton.Value = true;
            app.FrequencyButton = uiradiobutton(app.PlotButtonGroup);
            app.FrequencyButton.Text = 'Frequency';
            app.FrequencyButton.Interpreter = 'latex';
            app.SpectogramButton = uiradiobutton(app.PlotButtonGroup);
            app.SpectogramButton.Text = 'Spectogram';
            app.SpectogramButton.Interpreter = 'latex';
            app.TimeSliderLabel = uilabel(app.UIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.UIFigure);
            app.TimeSlider.Limits = [0.1 10];
            app.TimeSlider.MajorTicks = [0 2 4 6 8 10];
            app.TimeSlider.Step = 0.1;
            app.TimeSlider.Value = 1;
            app.AmplitudeSliderLabel = uilabel(app.UIFigure);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.UIFigure);
            app.AmplitudeSlider.Limits = [0.1 5];
            app.AmplitudeSlider.MajorTicks = [0 1 2 3 4 5];
            app.AmplitudeSlider.MajorTickLabels = {'0', '1', '2', '3', '4', '5'};
            app.AmplitudeSlider.Step = 0.1;
            app.AmplitudeSlider.Value = 1;
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditField.Limits = [1 3999];
            app.FrequencyEditField.Value = 100;
            app.PlaySoundSwitchLabel = uilabel(app.UIFigure);
            app.PlaySoundSwitchLabel.Text = 'Play Sound';
            app.PlaySoundSwitch = uiswitch(app.UIFigure, 'slider');
            app.DutyCycleEditFieldLabel = uilabel(app.UIFigure);
            app.DutyCycleEditFieldLabel.Text = 'Duty Cycle';
            app.DutyCycleEditField = uieditfield(app.UIFigure, 'numeric');
            app.DutyCycleEditField.Limits = [0 100];
            app.DutyCycleEditField.Value = 5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_under_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end